classdef BecBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BecBrowserUIFigure        matlab.ui.Figure
        ServerButtonGroup         matlab.ui.container.ButtonGroup
        LocalButton               matlab.ui.control.ToggleButton
        RemoteButton              matlab.ui.control.ToggleButton
        UnloadButton              matlab.ui.control.Button
        OpenDataPathButton        matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        KeyWordsEditField         matlab.ui.control.EditField
        KeyWordsEditFieldLabel    matlab.ui.control.Label
        SerialEditField2          matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        SerialEditField           matlab.ui.control.NumericEditField
        SerialEditFieldLabel      matlab.ui.control.Label
        SerialCheckBox            matlab.ui.control.CheckBox
        TrialNameDropDown         matlab.ui.control.DropDown
        TrialNameDropDownLabel    matlab.ui.control.Label
        EndDateDatePicker         matlab.ui.control.DatePicker
        EndDateDatePickerLabel    matlab.ui.control.Label
        StartDateDatePicker       matlab.ui.control.DatePicker
        StartDateDatePickerLabel  matlab.ui.control.Label
        SearchButton              matlab.ui.control.Button
        DataTable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        BecExp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("Config.mat","BecExpConfig")
            app.EndDateDatePicker.Value = datetime("today");
            app.TrialNameDropDown.Items = ["All";sortBecExpTrialName(BecExpConfig.TrialName)];
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.LoadButton.Enable = "off";
            app.OpenDataPathButton.Enable = "off";
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = "lithium_experiment";
                databaseTableName = "main";
            else
                databaseName = "lithium_experiment_local";
                databaseTableName = "main";
            end
            startTime = "'" + string(app.StartDateDatePicker.Value) + "'";
            endTime = "'" + string(app.EndDateDatePicker.Value) + " 23:59:59'";
            conn = createReader(databaseName);
            query = "SELECT ""SerialNumber"", ""DateTime"", ""Name""," + ...
                " ""ScannedParameter"", ""Description"", ""DataPath"" FROM "...
                + databaseTableName +" WHERE ""DateTime"" >= " + startTime + ...
                " AND ""DateTime"" <= " + endTime;
            if app.TrialNameDropDown.Value ~= "All"
                query = query + " AND ""Name"" = '" + ...
                    app.TrialNameDropDown.Value + "'";
            end
            if app.SerialCheckBox.Value
                query = query + " AND ""SerialNumber"" >= " +...
                    string(app.SerialEditField.Value) + ...
                    " AND ""SerialNumber"" <= " + string(app.SerialEditField2.Value);
            end
            if ~isempty(app.KeyWordsEditField.Value)
                keyWords = strtrim(split(app.KeyWordsEditField.Value,","));
                keyWords = "'%" + keyWords + "%'";
                keyWords = strjoin(keyWords,", ");
                keyWords = "(array[" + keyWords + "])";
                query = query + " AND ""Description"" ILIKE ALL" + keyWords;
            end
            data = pgFetch(conn,query);
            app.DataTable.Data = data;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.UnloadButton.Enable = "on";
            app.DataTable.Enable = "inactive";
            app.SearchButton.Enable = "off";
            if app.ServerButtonGroup.SelectedObject == app.RemoteButton
                databaseName = "lithium_experiment";
                databaseTableName = "main";
            else
                databaseName = "lithium_experiment_local";
                databaseTableName = "main";
            end
            conn = createReader(databaseName);

            app.BecExp = loadTrial(conn,databaseTableName,app.DataTable.Data.SerialNumber(app.DataTable.Selection));
            app.BecExp.show;
        end

        % Button pushed function: OpenDataPathButton
        function OpenDataPathButtonPushed(app, event)
            winopen(app.DataTable.Data.DataPath(app.DataTable.Selection));
        end

        % Clicked callback: DataTable
        function DataTableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            % displayColumn = event.InteractionInformation.DisplayColumn;
            if isempty(displayRow)
                app.LoadButton.Enable = "off";
                app.OpenDataPathButton.Enable = "off";
            else
                app.OpenDataPathButton.Enable = "on";
                if  app.UnloadButton.Enable == "off"
                    app.LoadButton.Enable = "on";
                end
            end
        end

        % Button pushed function: UnloadButton
        function UnloadButtonPushed(app, event)
            % Change buttons
            app.UnloadButton.Enable = "off";
            app.DataTable.Enable = "on";
            app.SearchButton.Enable = "on";
            app.OpenDataPathButton.Enable = "off";

            for ii = 1:numel(app.BecExp.AnalysisMethod)
                app.BecExp.(app.BecExp.AnalysisMethod(ii)).close;
            end

            % Delete
            app.BecExp.delete;
        end

        % Value changed function: TrialNameDropDown
        function TrialNameDropDownValueChanged(app, event)
            value = app.TrialNameDropDown.Value;
            if ~ismember(value,app.TrialNameDropDown_2.Items)
                app.TrialNameDropDown.Value = "All";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BecBrowserUIFigure = uifigure('Visible', 'off');
            app.BecBrowserUIFigure.Name = 'BecBrowser';
            app.BecBrowserUIFigure.Tag = 'BecBrowser';
            app.DataTable = uitable(app.BecBrowserUIFigure);
            app.DataTable.ColumnName = {'SerialNumber'; 'DateTime'; 'Name'; 'ScannedParameter'; 'Description'; 'DataPath'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = [true true true true false];
            app.DataTable.SelectionType = 'row';
            app.DataTable.ClickedFcn = createCallbackFcn(app, @DataTableClicked, true);
            app.DataTable.Multiselect = 'off';
            app.SearchButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.StartDateDatePickerLabel = uilabel(app.BecBrowserUIFigure);
            app.StartDateDatePickerLabel.Text = 'Start Date:';
            app.StartDateDatePicker = uidatepicker(app.BecBrowserUIFigure);
            app.StartDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.StartDateDatePicker.Value = datetime([2024 1 1]);
            app.EndDateDatePickerLabel = uilabel(app.BecBrowserUIFigure);
            app.EndDateDatePickerLabel.Text = 'End Date:';
            app.EndDateDatePicker = uidatepicker(app.BecBrowserUIFigure);
            app.EndDateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.TrialNameDropDownLabel = uilabel(app.BecBrowserUIFigure);
            app.TrialNameDropDownLabel.Text = 'Trial Name:';
            app.TrialNameDropDown = uidropdown(app.BecBrowserUIFigure);
            app.TrialNameDropDown.Editable = 'on';
            app.TrialNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialNameDropDownValueChanged, true);
            app.SerialCheckBox = uicheckbox(app.BecBrowserUIFigure);
            app.SerialCheckBox.Text = '';
            app.SerialEditFieldLabel = uilabel(app.BecBrowserUIFigure);
            app.SerialEditFieldLabel.Text = 'Serial #';
            app.SerialEditField = uieditfield(app.BecBrowserUIFigure, 'numeric');
            app.SerialEditField.Limits = [1 Inf];
            app.SerialEditField.RoundFractionalValues = 'on';
            app.SerialEditField.Enable = 'off';
            app.SerialEditField.Value = 1;
            app.Label = uilabel(app.BecBrowserUIFigure);
            app.Label.Text = '~';
            app.SerialEditField2 = uieditfield(app.BecBrowserUIFigure, 'numeric');
            app.SerialEditField2.Limits = [1 Inf];
            app.SerialEditField2.Enable = 'off';
            app.SerialEditField2.Value = 1;
            app.KeyWordsEditFieldLabel = uilabel(app.BecBrowserUIFigure);
            app.KeyWordsEditFieldLabel.Text = 'Key Words:';
            app.KeyWordsEditField = uieditfield(app.BecBrowserUIFigure, 'text');
            app.LoadButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.OpenDataPathButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.OpenDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataPathButtonPushed, true);
            app.OpenDataPathButton.Enable = 'off';
            app.OpenDataPathButton.Text = 'Open Data Path';
            app.UnloadButton = uibutton(app.BecBrowserUIFigure, 'push');
            app.UnloadButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadButtonPushed, true);
            app.UnloadButton.Enable = 'off';
            app.UnloadButton.Text = 'Unload';
            app.ServerButtonGroup = uibuttongroup(app.BecBrowserUIFigure);
            app.RemoteButton = uitogglebutton(app.ServerButtonGroup);
            app.RemoteButton.Text = 'Remote';
            app.RemoteButton.Value = true;
            app.LocalButton = uitogglebutton(app.ServerButtonGroup);
            app.LocalButton.Text = 'Local';
            % Show the figure after all components are created
            app.BecBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BecBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BecBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BecBrowserUIFigure)
        end
    end
end